classdef gui_codi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MesuresDropDownLabel            matlab.ui.control.Label
        MesuresDropDown                 matlab.ui.control.DropDown
        EscalaDropDownLabel             matlab.ui.control.Label
        EscalaDropDown                  matlab.ui.control.DropDown
        ModelsDropDownLabel             matlab.ui.control.Label
        ModelsDropDown                  matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        MesuresDropDown_2Label          matlab.ui.control.Label
        MesuresDropDown_2               matlab.ui.control.DropDown
        EscalaDropDown_2Label           matlab.ui.control.Label
        EscalaDropDown_2                matlab.ui.control.DropDown
        ModelsDropDown_2Label           matlab.ui.control.Label
        ModelsDropDown_2                matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        MULTIMETRE1Label                matlab.ui.control.Label
        MULTIMETRE2Label                matlab.ui.control.Label
        COMPARACIDELERRORDEMESURALabel  matlab.ui.control.Label
        MireiaAndreuPauLabel            matlab.ui.control.Label
        MireiaAndreuPauLabel_2          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        FiltreMultimetresListBoxLabel   matlab.ui.control.Label
        FiltreMultimetresListBox        matlab.ui.control.ListBox
        MagnitudDropDownLabel           matlab.ui.control.Label
        MagnitudDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
      
        dmms multimetre
        dmms_sel multimetre
        dmm1
        mesura1
        escala1
        dmm2
        mesura2
        escala2
        err0_1
        err0_2
        err1_1
        err1_2
        
        mesures mesura
        M = containers.Map('KeyType', 'char');
    end
    
    methods (Access = private)
        
        function actualitzarMenus(app)
            %Llista
                 
                        
            app.dmm1 = app.ModelsDropDown.Value;          
            app.MesuresDropDown.Items = [app.dmm1.magnitud.nom];
            app.MesuresDropDown.ItemsData = [app.dmm1.magnitud];
            app.mesures(1) = app.MesuresDropDown.Value;
            if(app.mesures(1).teEtiquetes)
                app.EscalaDropDown.Items = app.mesures(1).etiquetes;
            else
                app.EscalaDropDown.Items = string(app.mesures(1).rangs);
            end
            app.EscalaDropDown.ItemsData = [1:length(app.mesures(1).rangs)];
            app.escala1 = app.EscalaDropDown.Value;
            dig = app.mesures(1).digits(app.escala1);
            res = app.mesures(1).resolucio(app.escala1);
            fe = app.mesures(1).rangs(app.escala1);
            app.err0_1 = (res)*dig;
            app.err1_1 = app.mesures(1).errorMesura(app.escala1)/100*fe + app.err0_1;
         %   app.Label.Text = string(app.err0_1);
          %  app.Label_2.Text = string(app.err1_1);
            app.Label_5.Text = app.mesures(1).unitat;
            
            app.dmm2 = app.ModelsDropDown_2.Value;
            app.MesuresDropDown_2.Items = [app.dmm2.magnitud.nom];
            app.MesuresDropDown_2.ItemsData = [app.dmm2.magnitud];
            app.mesura2 = app.MesuresDropDown_2.Value;
            if(app.mesura2.teEtiquetes)
                app.EscalaDropDown_2.Items = app.mesura2.etiquetes;
            else
                app.EscalaDropDown_2.Items = string(app.mesura2.rangs);
            end
            app.EscalaDropDown_2.ItemsData = [1:length(app.mesura2.rangs)];
            app.escala2 = app.EscalaDropDown_2.Value;
            dig2 = app.mesura2.digits(app.escala2);
            res2 = app.mesura2.resolucio(app.escala2);
            fe2 = app.mesura2.rangs(app.escala2);
            app.err0_2 = res2*dig2;
            app.err1_2 = app.mesura2.errorMesura(app.escala2)/100*fe + app.err0_2;
          %  app.Label_3.Text = string(app.err0_2);
           % app.Label_4.Text = string(app.err1_2);
            app.Label_6.Text = app.mesura2.unitat;
            
            %Filtre


            actualitzarGrafic(app);
            
            
        end


        function actualitzarGrafic(app)
            

%             error_maxim_MULTIMETRE_1 = app.err1_1;
%             error_minim_MULTIMETRE_1 = app.err0_1;
%             
%             error_maxim_MULTIMETRE_2 = app.err1_2;
%             error_minim_MULTIMETRE_2 = app.err0_2;
            
            plot(app.UIAxes, [0,1,1,2],log10([app.err0_1, app.err1_1, app.err0_2, app.err1_2]));
            legend(app.UIAxes,{app.dmm1.model, app.dmm2.model},'Location','Best')
            app.UIAxes.XLim = [0 2]; 
            %app.UIAxes.YLim = [0 1.2*max(app.err1_1, app.err1_2)];
            title(app.UIAxes, app.dmm1.fabricant + " " + app.dmm1.model + " (" + app.mesures(1).nom + ")  i  " + app.dmm2.fabricant + " " + app.dmm2.model + " (" + app.mesura2.nom + ")");
            app.UIAxes.XLabel.String='|L/FE|';
            app.UIAxes.YLabel.String='|Error/FE|';
            %plot(app.UIAxes,[0,1],[error_minim_MULTIMETRE_1,error_maxim_MULTIMETRE_1])
            %hold(app.UIAxes);
            %plot(app.UIAxes,[0,1],[error_minim_MULTIMETRE_2,error_maxim_MULTIMETRE_2])


%             if (error_maxim_MULTIMETRE_2 > error_maxim_MULTIMETRE_1)
%                 app.UIAxes.YLim = [0 error_maxim_MULTIMETRE_2+2];
%             else
%                 app.UIAxes.YLim = [0 error_maxim_MULTIMETRE_1+2];
% 
%             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tmp = load("Taula_Dades.mat");%evalin('base', 'dvm');
            app.dmms = tmp.('dvm');
         % Create ModelsDropDown
            app.ModelsDropDown.Items = [app.dmms.model];
            app.ModelsDropDown.ItemsData = [app.dmms];
            
            app.ModelsDropDown_2.Items = [app.dmms.model];
            app.ModelsDropDown_2.ItemsData = [app.dmms];
            
            %crear selector global de mesures
            app.MagnitudDropDown.Items = mesuresDict(app.dmms).keys;
            %filtre
            app.FiltreMultimetresListBox.Items = [app.dmms.model];
            %app.FiltreMultimetresListBox.ItemsData = 1:length(app.dmms);
     
            actualitzarMenus(app);
        end

        % Value changed function: EscalaDropDown, EscalaDropDown_2, 
        % FiltreMultimetresListBox, MesuresDropDown, 
        % MesuresDropDown_2, ModelsDropDown, ModelsDropDown_2
        function menuActualitzat(app, event)
            actualitzarMenus(app);
            
        end

        % Value changed function: MagnitudDropDown
        function MagnitudDropDownValueChanged(app, event)
            app.dmms_sel = buscarDmms(app.dmms, app.MagnitudDropDown.Value);
            app.FiltreMultimetresListBox.Items = [app.dmms_sel.model];
            app.FiltreMultimetresListBox.Value = [app.dmms_sel.model];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MesuresDropDownLabel = uilabel(app.UIFigure);
            app.MesuresDropDownLabel.Text = 'Mesures';
            app.MesuresDropDown = uidropdown(app.UIFigure);
            app.MesuresDropDown.Items = {};
            app.MesuresDropDown.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.MesuresDropDown.Value = {};
            app.EscalaDropDownLabel = uilabel(app.UIFigure);
            app.EscalaDropDownLabel.Text = 'Escala';
            app.EscalaDropDown = uidropdown(app.UIFigure);
            app.EscalaDropDown.Items = {};
            app.EscalaDropDown.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.EscalaDropDown.Value = {};
            app.ModelsDropDownLabel = uilabel(app.UIFigure);
            app.ModelsDropDownLabel.Text = 'Models';
            app.ModelsDropDown = uidropdown(app.UIFigure);
            app.ModelsDropDown.Items = {'', ''};
            app.ModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.ModelsDropDown.Value = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sense dades')
            xlabel(app.UIAxes, '|L/FE|')
            ylabel(app.UIAxes, '|Error/FE|')
            app.MesuresDropDown_2Label = uilabel(app.UIFigure);
            app.MesuresDropDown_2Label.Text = 'Mesures';
            app.MesuresDropDown_2 = uidropdown(app.UIFigure);
            app.MesuresDropDown_2.Items = {};
            app.MesuresDropDown_2.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.MesuresDropDown_2.Value = {};
            app.EscalaDropDown_2Label = uilabel(app.UIFigure);
            app.EscalaDropDown_2Label.Text = 'Escala';
            app.EscalaDropDown_2 = uidropdown(app.UIFigure);
            app.EscalaDropDown_2.Items = {};
            app.EscalaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.EscalaDropDown_2.Value = {};
            app.ModelsDropDown_2Label = uilabel(app.UIFigure);
            app.ModelsDropDown_2Label.Text = 'Models';
            app.ModelsDropDown_2 = uidropdown(app.UIFigure);
            app.ModelsDropDown_2.Items = {'', ''};
            app.ModelsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.ModelsDropDown_2.Value = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.MULTIMETRE1Label = uilabel(app.UIFigure);
            app.MULTIMETRE1Label.Text = 'MULTIMETRE 1';
            app.MULTIMETRE2Label = uilabel(app.UIFigure);
            app.MULTIMETRE2Label.Text = 'MULTIMETRE 2';
            app.COMPARACIDELERRORDEMESURALabel = uilabel(app.UIFigure);
            app.COMPARACIDELERRORDEMESURALabel.Text = 'COMPARACIÓ DEL ERROR DE MESURA';
            app.MireiaAndreuPauLabel = uilabel(app.UIFigure);
            app.MireiaAndreuPauLabel.Text = {'Mireia Andreu, Pau Besses, Paula Campiña, Eva Maria Deltor, Adria Freixa, Pau Jordan Oliveras, Joan Peña'; ''};
            app.MireiaAndreuPauLabel_2 = uilabel(app.UIFigure);
            app.MireiaAndreuPauLabel_2.Text = {'GIE02'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '23/05/2020';
            app.FiltreMultimetresListBoxLabel = uilabel(app.UIFigure);
            app.FiltreMultimetresListBoxLabel.Text = 'Filtre Multimetres';
            app.FiltreMultimetresListBox = uilistbox(app.UIFigure);
            app.FiltreMultimetresListBox.Multiselect = 'on';
            app.FiltreMultimetresListBox.ValueChangedFcn = createCallbackFcn(app, @menuActualitzat, true);
            app.FiltreMultimetresListBox.Value = {'Item 1'};
            app.MagnitudDropDownLabel = uilabel(app.UIFigure);
            app.MagnitudDropDownLabel.Text = 'Magnitud';
            app.MagnitudDropDown = uidropdown(app.UIFigure);
            app.MagnitudDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnitudDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_codi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end